classdef APP_FINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        CommandpromtEditField       matlab.ui.control.EditField
        CommandpromtEditFieldLabel  matlab.ui.control.Label
        FolderEditField             matlab.ui.control.EditField
        FolderEditFieldLabel        matlab.ui.control.Label
        SaveVASAButton              matlab.ui.control.Button
        volumerunButton             matlab.ui.control.Button
        Run2DButton                 matlab.ui.control.Button
        PixelEditField              matlab.ui.control.EditField
        PixelEditFieldLabel         matlab.ui.control.Label
        pushbutton5                 matlab.ui.control.Button
        liffnme                     matlab.ui.control.EditField
        text5                       matlab.ui.control.Label
        axes2                       matlab.ui.control.UIAxes
        axes1                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function cellcnt_Callback(app, hObject, eventdata, handles)
            % hObject    handle to cellcnt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of cellcnt as text
            %        str2double(get(hObject,'String')) returns contents of cellcnt as a double
        end
        
        function cellcnt_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to cellcnt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function day_Callback(app, hObject, eventdata, handles)
            % hObject    handle to day (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of day as text
            %        str2double(get(hObject,'String')) returns contents of day as a double
        end
        
        function day_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to day (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function delpts_ButtonDownFcn(app, hObject, eventdata, handles)
            % hObject    handle to delpts (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            hndls = guidata(hObject);
            
            chldrn = get(hndls.axes1,'children');
            lnehndle = chldrn(strcmp(get(chldrn,'type'),'line'));
            delete(lnehndle);
            
            % hndle=plot(cellsx,cellsy,'wx','markersize',3);
            % set(hndle,'hittest','off');
            set(hndls.cellcnt,'String',num2str(0));
            hndls.cells = zeros(0,2);
            guidata(hObject,hndls);
        end
        
        function delpts_Callback(app, hObject, eventdata, handles)
            % hObject    handle to delpts (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            hndls = guidata(hObject);
            
            chldrn = get(hndls.axes1,'children');
            lnehndle = chldrn(strcmp(get(chldrn,'type'),'line'));
            delete(lnehndle);
            
            % hndle=plot(cellsx,cellsy,'wx','markersize',3);
            % set(hndle,'hittest','off');
            set(hndls.cellcnt,'String',num2str(0));
            hndls.cells = zeros(0,2);
            guidata(hObject,hndls);
        end
        
        function imgButtonDownFcn(app, hObject, eventdata)
            % hObject    handle to axes1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % cntrds = handles.cells;
            
            hndls = guidata(hObject);
            nwcntrd = get(hndls.axes1,'CurrentPoint');
            nwpt = nwcntrd(1,1:2);
            
            chldrn = get(hndls.axes1,'children');
            lnehndle = chldrn(strcmp(get(chldrn,'type'),'line'));
            cellsx = get(lnehndle,'xdata'); cellsy = get(lnehndle,'ydata');
            
            % cntrds = cat(1,hndls.cells)
            
            
            clck = get(gcbf,'SelectionType');
            
            if strcmp(clck,'normal')
                cellsx(end+1)=nwpt(1); cellsy(end+1)=nwpt(2);
            
            else
                [~,indx] = min((cellsx-nwpt(1)).^2 + (cellsy-nwpt(2)).^2);
                cellsx(indx)=[]; cellsy(indx)=[];
            
            end
            
            delete(lnehndle);
            hndle=plot(cellsx,cellsy,'wx','markersize',3);
            % set(hndle,'hittest','off');
            set(hndls.cellcnt,'String',num2str(length(cellsx)));
            hndls.cells = [cellsx cellsy];
            guidata(hObject,hndls);
            % fprintf('Mouse pressed at %f, %f\n',nwcell(1,1),nwcell(1,2));
            % fprintf('%s\n',get(gcbf,'SelectionType'));
        end
        
        function imgButtonDownFcn1(app, hObject, eventdata)
            % hObject    handle to axes2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles = guidata(hObject);
            
            nwcntrd = get(handles.axes2,'CurrentPoint');
            nwpt = nwcntrd(1,1:2);
            
            clck = get(gcbf,'SelectionType');
            fldr=app.FolderEditField.Value;
%             fldr = get(handles.fnmetxt,'String');
            prfx = get(handles.liffnme,'String');
            
            matfnme = [fldr '\' prfx '.mat'];
            load(matfnme);
            
            cntrds=zeros(length(dat),2);
            
            for ncls=1:length(dat)
                if dat(ncls).include==true
                    O=dat(ncls).boundary;
                    cntrds(ncls,:)=mean(O,1);
                end
            end
            
            d = sqrt((cntrds(:,1)-nwpt(2)).^2+(cntrds(:,2)-nwpt(1)).^2);
            [~,id]=min(d);
            axes(handles.axes2); hold on;
            if strcmp(clck,'normal')
                plot(cntrds(id,2),cntrds(id,1),'rx','markersize',12);
                dat(id).include=false;
            else
                plot(cntrds(id,2),cntrds(id,1),'bx','markersize',12);
                dat(id).include=true;
            end
            
            drawnow;
            
            save(matfnme,'dat');
        end
        
        function imgnum_Callback(app, hObject, eventdata, handles)
            % hObject    handle to imgnum (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of imgnum as text
            %        str2double(get(hObject,'String')) returns contents of imgnum as a double
        end
        
        function imgnum_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to imgnum (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function n_imgs_Callback(app, hObject, eventdata, handles)
            % hObject    handle to n_imgs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of n_imgs as text
            %        str2double(get(hObject,'String')) returns contents of n_imgs as a double
        end
        
        function n_imgs_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to day (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function txtout_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to txtout (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            % set(handles.txtout,'String','');
            set(hObject,'String','');
        end
        
        function typ_Callback(app, hObject, eventdata, handles)
            % hObject    handle to typ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of typ as text
            %        str2double(get(hObject,'String')) returns contents of typ as a double
        end
        
        function typ_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to day (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end


    methods (Access = public)

        function dat= algo_snakes_noadj1(app,img,pxl,enhance)

            dmin=5; %minimum diameter of nucleus is um
            dmax=40; %maximum diameter of nucleus in um
            
            Options.Verbose=false;
            Options.Iterations=2000;
            Options.GIterations=200;
            Options.Alpha=0.02;
            Options.Beta=0.05;
            Options.Kappa=1;
            Options.Delta=0;
            Options.Wline=0; Options.Wterm=0;
            Options.Mu=0.1;
            Options.Sigma1=1;
            Options.Sigma2=1;
            Options.Sigma3=1;
            
            Options.Exitcon=0.01;
            load jetcolormap.mat;
            
            dmxpxl=dmax/pxl; dmnpxl=dmin/pxl;
            
            Amin=pi*(dmin^2)/4; amnpxl=Amin/(pxl^2);
            Amax=pi*(dmax^2)/4; amxpxl=Amax/(pxl^2);
            
            if length(size(img))>2
                img=img(:,:,1:3);
                gry = rgb2gray(img);
            else
                gry = img;
            end
                
            szex = size(gry,2); szey=size(gry,1);
            
            %enhancing the image
            if enhance
                enhnc = adapthisteq(gry,'cliplimit',0.05,'distribution','rayleigh');
                enhnc = medfilt2(enhnc,[2 2]);
            %     imshow(enhnc); hold on;
            else
                enhnc = gry;
            %     imshow(gry); hold on;
            end
            %thresholding
            thres = graythresh(enhnc);
            bw = imbinarize(enhnc,thres);
            
            %removing the small regions
            bwrmsml = bwareaopen(bw,ceil(amnpxl));
            
            %opening to remove noise - radius 10% of the smallest nuclei
            bwnoiserm = imopen(bwrmsml,strel('disk',ceil(dmnpxl/10)));
            
            %removing the small regions
            bwrmsml = bwareaopen(bwnoiserm,ceil(amnpxl));
            
            %filling the holes
            bwfl = bwfill(bwrmsml,'holes');
            
            %smoothing and enlarging
            bwenlrge = imdilate(imerode(bwfl,strel('disk',3)),strel('disk',6));
            
            %removing the small regions
            hullrmsml = bwareaopen(bwenlrge,ceil(amnpxl));
            
            % convex hull for snake outer boundaries
            stats = regionprops(bwfl,'ConvexHull','ConvexImage','PixelList','Centroid');
            
            %adjusting the original image
            % adj = imadjust(gry); 
            % adj=gry;
            
            for nst=1:length(stats)
                pxls=stats(nst).PixelList;
                if any([any(pxls(:,1)==1) any(pxls(:,2)==1) any(pxls(:,1)==szex) any(pxls(:,2)==szey)])
                    dat(nst).include=false;
                else
                    fprintf('Optimising nucleus %d of maximum %d nuclei\n',nst,length(stats));
                    pts=stats(nst).ConvexHull;
                    ocntrd=stats(nst).Centroid;
                    %bounds of the convex hull
                    bnds = [floor(min(pts(:,1))) ceil(max(pts(:,1))) floor(min(pts(:,2))) ceil(max(pts(:,2)))];
                    bnds = bnds + double(bnds==0);
                    %innerhull
                    hullclp = hullrmsml(bnds(3):bnds(4),bnds(1):bnds(2));
                    inhullerdd = imerode(hullrmsml,strel('disk',2));
                    stats2 = regionprops(inhullerdd,'ConvexHull','Perimeter','Centroid');
                    if length(stats2)>1
                        cntrds=reshape([stats2.Centroid],[2 length(stats2)])';
                        [~,id]=min(sqrt((cntrds(:,1)-ocntrd(1)).^2 + (cntrds(:,2)-ocntrd(2)).^2));
                        pts2=stats2(id).ConvexHull;
                    else
                        pts2=stats2.ConvexHull;
                    end
                    %clipping the adjusted image
                    adjclp = enhnc(bnds(3):bnds(4),bnds(1):bnds(2));
                    %adjusting the clipped image
            %         adjclp = imadjust(gryclp);
                    %size of the clipped image
                    clpx = bnds(2)-bnds(1)+1; clpy = bnds(4)-bnds(3)+1;
                    %setting pixels outside the hull to zero
                    ohullmsk= poly2mask(pts(:,1)-bnds(1),pts(:,2)-bnds(3),clpy,clpx);
                    adjclpflt = adjclp.*uint8(ohullmsk);
                    %adjusting the image again
                    adjadj = imadjust(adjclp);
                    %thresholding between 70% and 130% of the Otsu's threshold value
                    thres1 = graythresh(adjadj);
                    if thres1
                        almstthres = imadjust(adjadj,[0.7*thres1 min(1.3*thres1,1)]);
                    else
                        almstthres = adjadj;
                    end
                    %filling the holes
                    fld = imfill(almstthres,'holes');
                    %calculating the perimeter of the initial contour
                    xcrd=pts2(:,2)-bnds(3); ycrd=pts2(:,1)-bnds(1);
                    perim = CurveLength2D([xcrd; xcrd(1)],[ycrd; ycrd(1)]);
                    %setting the number of points to 5 per um
                    Options.nPoints = ceil(perim*pxl*5);
                    dbl = im2double(fld);
                    %snake algorithm to converge to the boundary of the nuclei
                    O=Snake2D(dbl,[pts2(:,2)-bnds(3) pts2(:,1)-bnds(1)] ,Options);
                    %removing nuclei less than a threshold area
                    if abs(polyarea(O(:,1),O(:,2)))>amnpxl/3 && abs(polyarea(O(:,1),O(:,2)))<amxpxl
                        %interpolate points to equal distance along the boundary
                        Od=InterpolateContourPoints2D(O,Options.nPoints);
                        %translating the nuclei boundary to that in the original image
                        Od=[Od(:,1)+bnds(3) Od(:,2)+bnds(1)];
                        dat(nst).boundary=Od;cntrd=mean(Od);
                        %plotting the boundary
            %             figure(1);
            %             plot(Od(:,2),Od(:,1));
            %             drawnow;
                        dat(nst).include=true;
                    else
                        dat(nst).include=false;
                    end
                end
            end
            
            end
        
        function results = read_stack_proj_run2D1(app,fldr,pxl)
                        %reading the readme file
            rnme = [fldr '\readme.txt'];
            rd = textread(rnme,'%s','delimiter','\n');
                
            for npat = 1:length(rd)/3
                %processing the readme file
                fnmepat = rd{3*npat-2};
                matpat = rd{3*npat-1};
                imgpat  = rd{3*npat};
                eval(['fnmes =' fnmepat ';']);
                
            
                %looping through each image stack
                for nf=1:length(fnmes)
                    bnme = sprintf(matpat,fnmes(nf));
                    fprintf('Processing %s in %s\n',bnme,fldr);
                    imgsnme = sprintf(imgpat,fnmes(nf));
                    %file name pattern for each file in the stack
                    lst = dir([fldr '\' imgsnme]); szez = length(lst);
                    %building the stack
                    for nimgs=1:szez
                        tmp = imread([fldr '\' lst(nimgs).name]);
                        if length(size(tmp))==3
                            zimg = squeeze(tmp(:,:,3));
                        else
                            zimg = tmp;
                        end
                        img(:,:,nimgs) = zimg;
                    end
                    %calculating the maximum projection
                    imgz = im2uint8(max(img,[],3));
                    %running the 2D algorithm on the maximum projection
                    dat = algo_snakes_noadj1(app,imgz,pxl(1),false);
                    %saving the pixel size for each nucleus
                    for ndat=1:length(dat)
                        dat(ndat).pxl = pxl;
                    end
                    %saving the mat file containing the nucleus data
                    save([fldr '\' bnme '.mat'],'dat');
                    %writing the maximum projection image file
                    imwrite(imgz,[fldr '\' bnme '.tif']);
                    clear img;
                end
            end
        end


            function results = volume_stack_folder1(app,fldr)
                        %VOLUME_STACK_FOLDER Summary of this function goes here
            %   Detailed explanation goes here
            
            %reading the readme file
            rnme = [fldr '\readme.txt'];
            rd = textread(rnme,'%s','delimiter','\n');
                
            for npat = 1:length(rd)/3
                %processing the readme file
                fnmepat = rd{3*npat-2};
                matpat = rd{3*npat-1};
                imgpat  = rd{3*npat};
                eval(['fnmes =' fnmepat ';']);
            
                %looping through each image stack
                for nf=1:length(fnmes)
                    bnme = sprintf(matpat,fnmes(nf));
                    fprintf('Processing %s in %s\n',bnme,fldr);
                    imgsnme = sprintf(imgpat,fnmes(nf));
                    %file name pattern for each file in the stack
                    lst = dir([fldr '\' imgsnme]); szez = length(lst);
                    %building the stack
                    for nimgs=1:szez
                        tmp = imread([fldr '\' lst(nimgs).name]);
                        if ~strcmp(class(tmp),'uint8')
                            tmp = im2uint8(tmp);
                        end
                        if length(size(tmp))==3
                            zimg = squeeze(tmp(:,:,3));
                        else
                            zimg = tmp;
                        end
                        img(:,:,nimgs) = zimg;
                    end
            %         img = imresize3(img,[size(img,1)/2 size(img,2)/2 size(img,3)]);
                    %loading the mat file containing the 2D nuclear information
                    load([fldr '\' bnme '.mat']);
                    %running the 3D algorithm
                    dat = volume_stack_dat1(app,img,dat);
                    %saving the 3D morphologies to the .mat file
                    save([fldr '\' bnme '.mat'],'dat');
                    clear img;
                end
            end
            
            end

        
        function dat = volume_stack_dat1(app,whl,dat)
            wx=size(whl,2); wy=size(whl,1);
            n_dat = length(dat);
            
            for ndat = 1:n_dat
            
                if ~dat(ndat).include 
                    dat(ndat).surf = [];
                else 
                    fprintf('Nucleus %d of maximum %d nuclei\n',ndat,n_dat);
                    ncl = dat(ndat).boundary; 
                    bndrybx = [floor(min(ncl(:,1))) ceil(max(ncl(:,1))) floor(min(ncl(:,2))) ceil(max(ncl(:,2)))];
                    bndrybx = [bndrybx(1)-5 bndrybx(2)+5 bndrybx(3)-5 bndrybx(4)+5];
                    bndrybx = bndrybx + double(bndrybx==0);
                    bndrybx(1) = max(bndrybx(1),1); bndrybx(3) = max(bndrybx(3),1); 
                    bndrybx(2) = min(wx,bndrybx(2)); bndrybx(4) = min(wy,bndrybx(4));
            
                    img = whl(bndrybx(1):bndrybx(2),bndrybx(3):bndrybx(4),:);
                    [dat(ndat).surf, dat(ndat).settings] = algo_snakes_3D(img);
                end
            end
            
            end
        
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function remove_nuclei_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to remove_nuclei (see VARARGIN)
            
            % Choose default command line output for remove_nuclei
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            cla;
            % set(handles.txtout,'String','');
            
            % UIWAIT makes remove_nuclei wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
        end

        % Callback function
        function fldr_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to fldr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             fldrnme = uigetdir('E:\data\Channel');
            fldrnme=app.FolderEditField.Value;
            set(handles.fnmetxt,'String',fldrnme);
        end

        % Value changed function: liffnme
        function liffnme_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to liffnme (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of liffnme as text
            %        str2double(get(hObject,'String')) returns contents of liffnme as a double
        end

        % Button pushed function: pushbutton5
        function pushbutton5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
%             fldr = get(handles.fnmetxt,'String');
            fldr=app.FolderEditField.Value;
            prfx = get(handles.liffnme,'String');
            imgfnme = [fldr '\' prfx '.tif'];
            matfnme = [fldr '\' prfx '.mat'];
            
            img=imread(imgfnme);
            
            if length(size(img))==3
                img=img(:,:,1:3);
                gry = rgb2gray(img);
            else
                gry = img;
            end
            
            load(matfnme);
            
            % enhnc = adapthisteq(gry,'cliplimit',0.02,'distribution','rayleigh');
            enhnc = gry;
            
            axes(handles.axes1); cla; hold on;
            imshow(enhnc); axis tight;
            % axes(handles.axes1); cla; hold on;
            % imshow(gry,'border','tight'); axis tight;
            
            axes(handles.axes2); cla; hold on;
            himg=imshow(enhnc); axis tight;
            % axes(handles.axes2); cla; hold on;
            % himg=imshow(gry,'border','tight'); axis tight;
            set(himg,'ButtonDownFcn',@app.imgButtonDownFcn1);
            
            for ncls=1:length(dat)
                if dat(ncls).include==true
                    O=dat(ncls).boundary;
                    axes(handles.axes2);
                    plot(O(:,2),O(:,1),'r-');
                end
            end
        end

        % Button pushed function: Run2DButton
        function Run2DButtonPushed(app, event)
             %housekeeping commands
%           clearvars; close all; clc;

%           folder containing the snake algorithms
            addpath('.\BasicSnake');
%           size of one voxel in um
            pxl=str2num(app.PixelEditField.Value);
%              pxl=[0.2 0.2 0.5]; 


            % folder containing the data - stacks

            fldr= app.FolderEditField.Value;
            read_stack_proj_run2D1(app,fldr,pxl)


        end

        % Button pushed function: volumerunButton
        function volumerunButtonPushed(app, event)
%             volume_manyfolders

            %housekeeping commands
%           clearvars; close all; clc;
%           pxl = [0.2 0.2 0.5];
            pxl=str2num(app.PixelEditField.Value);
            %folder containing the snake algorithm
            addpath('.\BasicSnake');

        
           fldr= app.FolderEditField.Value;
%          fldr = 'C:\Users\HP\Downloads\nuclei_imageprocessing_toolbox\sample 4-stack';
           volume_stack_folder1(app,fldr);

        
        end

        % Button pushed function: SaveVASAButton
        function SaveVASAButtonPushed(app, event)
            %housekeeping commands
%             clearvars; close all; 
            %initialising variables
            ar3 = zeros(0,1); vol = zeros(0,1); ecc = zeros(0,1); srfar = zeros(0,1);
            %folder containing the data
            fldr= app.FolderEditField.Value;
            % fldr = 'C:\Users\HP\Downloads\nuclei_imageprocessing_toolbox\sample 1 stack';
            %output file which will have the areas, volume and eccentricity of nuclei
            ofnme = [fldr '\areas_vol.mat'];
            
            %reading the readme file
            rnme = [fldr '\readme.txt'];
            rd = textread(rnme,'%s','delimiter','\n'); cnt=1;
            
            for npat = 1:length(rd)/3
                %processing the readme file
                fnmepat = rd{3*npat-2};
                matpat = rd{3*npat-1};
                eval(['fnmes =' fnmepat ';']);
            
                for nf=1:length(fnmes)
                    %reading and loading individual .mat files
                    fnme = sprintf(matpat,fnmes(nf));
                    load([fldr '\' fnme '.mat']);
                    %looping over individual nuclei
                    for ndat=1:length(dat)
                        %checking if the nucleus has been excluded
                        if dat(ndat).include
                            %reading the voxel size
                            xypxl = dat(ndat).pxl(1); zpxl=dat(ndat).pxl(3);
                            %reading the 3D nuclear surface
                            OV = dat(ndat).surf;
                            %scaling the surface according to the voxel size
                            OV.vertices = OV.vertices*diag([xypxl,xypxl,zpxl]);
                            %projecting the surface for the projected area and
                            %eccentricity
                            pts = OV.vertices(unique(OV.faces),:);
                            ptsprj = pts(:,1:2);
                            fcs = delaunay(ptsprj); n_fcs=size(fcs,1);
                            %projected area
                            area3 = sum(abs(polyarea(reshape(pts(fcs',1),[3 n_fcs]),reshape(pts(fcs',2),[3 n_fcs]))));
                            [vol3, srfar3]=stlVolume(OV.vertices',OV.faces'); 
                            ar3 = [ar3; area3]; %projected area
                            vol = [vol; vol3];  %volume
                            srfar = [srfar; srfar3]; %surface area
                            dat(ndat).PA=area3; dat(ndat).SA=srfar3; dat(ndat).V=vol3;
            end
        end
        save([fldr '\' fnme '.mat'],'dat');
    end
end

%printing the mean values to the command window
[mean(ar3) mean(vol) mean(srfar)]
save(ofnme,'ar3','vol','srfar');
        end

        % Value changed function: CommandpromtEditField
        function CommandpromtEditFieldValueChanged(app, event)
            value = app.CommandpromtEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'remove_nuclei';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Image No:';
            app.liffnme = uieditfield(app.figure1, 'text');
            app.liffnme.ValueChangedFcn = createCallbackFcn(app, @liffnme_Callback, true);
            app.liffnme.Tag = 'liffnme';
            app.pushbutton5 = uibutton(app.figure1, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5_Callback, true);
            app.pushbutton5.Tag = 'pushbutton5';
            app.pushbutton5.Text = 'Remove Nuclei';
            app.PixelEditFieldLabel = uilabel(app.figure1);
            app.PixelEditFieldLabel.Text = 'Pixel [ ]';
            app.PixelEditField = uieditfield(app.figure1, 'text');
            app.Run2DButton = uibutton(app.figure1, 'push');
            app.Run2DButton.ButtonPushedFcn = createCallbackFcn(app, @Run2DButtonPushed, true);
            app.Run2DButton.Text = 'Run 2D';
            app.volumerunButton = uibutton(app.figure1, 'push');
            app.volumerunButton.ButtonPushedFcn = createCallbackFcn(app, @volumerunButtonPushed, true);
            app.volumerunButton.Text = 'volume run';
            app.SaveVASAButton = uibutton(app.figure1, 'push');
            app.SaveVASAButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVASAButtonPushed, true);
            app.SaveVASAButton.Text = 'Save V, A, SA';
            app.FolderEditFieldLabel = uilabel(app.figure1);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.figure1, 'text');
            app.CommandpromtEditFieldLabel = uilabel(app.figure1);
            app.CommandpromtEditFieldLabel.Text = 'Command promt';
            app.CommandpromtEditField = uieditfield(app.figure1, 'text');
            app.CommandpromtEditField.ValueChangedFcn = createCallbackFcn(app, @CommandpromtEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_FINAL(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)remove_nuclei_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end